classdef yanyuban < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PCALabel                     matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        Label_15                     matlab.ui.control.Label
        Label_14                     matlab.ui.control.Label
        Label_13                     matlab.ui.control.Label
        Panel_7                      matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        Panel_4                      matlab.ui.container.Panel
        Panel_5                      matlab.ui.container.Panel
        Label2                       matlab.ui.control.Label
        Panel_6                      matlab.ui.container.Panel
        Button_19                    matlab.ui.control.Button
        Button_18                    matlab.ui.control.Button
        Button_17                    matlab.ui.control.Button
        TextArea_3                   matlab.ui.control.TextArea
        Label_11                     matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Label_9                      matlab.ui.control.Label
        Button_10                    matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        Button_5                     matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Slider_4                     matlab.ui.control.Slider
        Label_7                      matlab.ui.control.Label
        Slider_3                     matlab.ui.control.Slider
        Label_6                      matlab.ui.control.Label
        Slider_2                     matlab.ui.control.Slider
        Label_5                      matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        Slider                       matlab.ui.control.Slider
        Label_4                      matlab.ui.control.Label
        Button_4                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Tab_2                        matlab.ui.container.Tab
        Image10                      matlab.ui.control.Image
        Image9                       matlab.ui.control.Image
        Image8                       matlab.ui.control.Image
        Image7                       matlab.ui.control.Image
        Image6                       matlab.ui.control.Image
        Image5                       matlab.ui.control.Image
        Panel_11                     matlab.ui.container.Panel
        UIAxes6                      matlab.ui.control.UIAxes
        Panel_10                     matlab.ui.container.Panel
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        Panel_8                      matlab.ui.container.Panel
        Button_25                    matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        Label_17                     matlab.ui.control.Label
        Button_24                    matlab.ui.control.StateButton
        Button_23                    matlab.ui.control.Button
        Slider_5                     matlab.ui.control.Slider
        Label_16                     matlab.ui.control.Label
        PCAButton                    matlab.ui.control.Button
        Button_22                    matlab.ui.control.Button
        Tab_3                        matlab.ui.container.Tab
        Button_29                    matlab.ui.control.Button
        Button_30                    matlab.ui.control.Button
        RBFButton                    matlab.ui.control.Button
        Button_28                    matlab.ui.control.Button
        PolynomialOrderEditField     matlab.ui.control.EditField
        PolynomialOrderLabel         matlab.ui.control.Label
        KernelScaleEditField         matlab.ui.control.NumericEditField
        KernelScaleEditFieldLabel    matlab.ui.control.Label
        TextArea_5                   matlab.ui.control.TextArea
        BoxConstraintEditField       matlab.ui.control.NumericEditField
        BoxConstraintEditFieldLabel  matlab.ui.control.Label
        Image11                      matlab.ui.control.Image
        PCATab                       matlab.ui.container.Tab
        Button_37                    matlab.ui.control.Button
        Button_36                    matlab.ui.control.Button
        P85Button                    matlab.ui.control.Button
        Button_35                    matlab.ui.control.Button
        Button_34                    matlab.ui.control.Button
        Button_33                    matlab.ui.control.Button
        Button_32                    matlab.ui.control.Button
        Button_31                    matlab.ui.control.Button
        Image12                      matlab.ui.control.Image
        Tab_4                        matlab.ui.container.Tab
    end

    
    properties (Access = public)
        CurrentImage;
        image_path;
        gray_image;
        OriginalImage;
        trainData;
        meanFace;
        diffFaces;
        eigenfaces;
        trainWeights;
        threshold;
        IsRunning;
        contributionRate;
        SelectedKernelType = 'linear';
        contributionRate2;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
           %灰度化
            if isempty(app.UIAxes.Children)
                uialert(app.UIFigure, '请先上传图像！', '错误');
                return;
            end

            % 获取当前显示的图像数据
            img = app.UIAxes.Children.CData; % 直接从坐标区域获取图像数据

            % 灰度化处理（如果图像是彩色的）
            if size(img, 3) == 3 % 检查是否为RGB图像
                grayImg = rgb2gray(img); % 转换为灰度图像
            else
                grayImg = img; % 已经是灰度图像
            end
            app.gray_image=grayImg;
            app.CurrentImage=grayImg;

            % 显示灰度图像
            imshow(grayImg, 'Parent', app.UIAxes);
            
            % 更新图像信息（可选）
            
            figure(app.UIFigure);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %打开文件
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Select an Image');

            % 检查用户是否取消选择
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            % 构建完整文件路径
            fullPath = fullfile(pathname, filename);

            try
                img = imread(fullPath);
                app.image_path=fullPath;
                imshow(img, 'Parent', app.UIAxes);  % 显示到名为UIAxes的坐标轴组件
                app.CurrentImage = img;
                app.OriginalImage=img;
                app.Slider.Value = 50;
                app.Slider_2.Value = 50;

                % 存储原始图像

            catch ME
                errordlg(['Error: ', ME.message], 'Image Load Failed');
            end
            figure(app.UIFigure);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %删除文件
            % 获取坐标区内的所有图像对象
            imgHandles = findobj(app.UIAxes, 'Type', 'image');

            % 删除图像对象
            if ~isempty(imgHandles)
                cla(app.UIAxes);
                reset(app.UIAxes);  % 重置坐标轴属性
                title(app.UIAxes, '');  % 清除标题  % 仅删除图像，保留其他图形
            end

            if isprop(app, 'CurrentImage')
                app.CurrentImage = [];
            end
            figure(app.UIFigure);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %均衡化
            if isempty(app.UIAxes.Children)
                uialert(app.UIFigure, '坐标区域没有图像！请先加载图像', '错误');
                return;
            end

            % 获取当前显示的图像数据
            currentImage = app.UIAxes.Children.CData;

            % 条件2：检测图像是否为灰度
            if size(currentImage, 3) == 3
                uialert(app.UIFigure, '图像必须是灰度化的！请先点击"灰度化"按钮', '错误');
                return;
            end

            % 执行直方图均衡化
            eqImg = histeq(currentImage);

            % 显示处理后的图像
            imshow(eqImg, 'Parent', app.UIAxes);

            % 更新状态信息（根据实际界面元素调整）
            if isprop(app, 'ImageInfoTextArea')
                app.ImageInfoTextArea.Value = '图像均衡化完成';
            end
            figure(app.UIFigure);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %自动
            value = app.DropDown.Value;
            switch value
                case '自动'
                    % 自动模式下不执行任何操作
                    return;

                case '原图'
                    cla(app.UIAxes);
                    imshow(app.CurrentImage, 'Parent', app.UIAxes);
                case '新图'
                    % 清除坐标区域图像
                    cla(app.UIAxes);
                    reset(app.UIAxes);  % 重置坐标轴属性
                    title(app.UIAxes, '');  % 清除标题

                    
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
           %调节亮度
            brightnessValue = app.Slider.Value;


            % 检查是否有图像加载
            if isempty(app.CurrentImage)
                return;
            end
            cla(app.UIAxes);
            % 将亮度值从[0,100]映射到[0.3,1.7]范围
            % 50对应无调整(1.0)，0对应变暗(0.3)，100对应变亮(1.7)
            brightnessFactor = 0.3 + (brightnessValue/100)*1.4;

            % 应用亮度调整
            adjustedImg = im2double(app.CurrentImage) * brightnessFactor;

            % 确保像素值在[0,1]范围内
            adjustedImg(adjustedImg > 1) = 1;
            adjustedImg(adjustedImg < 0) = 0;

            % 更新显示
            adjustedImage = im2uint8(adjustedImg);
            app.CurrentImage = adjustedImage;
            imshow(adjustedImage, 'Parent', app.UIAxes);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            %调节对比度
            contrastValue = app.Slider_2.Value;
            % 检查是否有图像加载
            if isempty(app.CurrentImage)
                return;
            end
            cla(app.UIAxes);
            % 将对比度值从[0,100]映射到调整参数
            % 50对应无调整(1.0)，0对应低对比度(0.5)，100对应高对比度(1.5)
            contrastFactor = 0.5 + (contrastValue/100);

            % 应用对比度调整
            adjustedImg = im2double(app.CurrentImage);
            adjustedImg = (adjustedImg - 0.5) * contrastFactor + 0.5;

            % 确保像素值在[0,1]范围内
            adjustedImg(adjustedImg > 1) = 1;
            adjustedImg(adjustedImg < 0) = 0;

            % 更新显示
            adjustedImage = im2uint8(adjustedImg);
            app.CurrentImage = adjustedImage;
            imshow(adjustedImage, 'Parent', app.UIAxes);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            % 锐化回调函数（兼容彩色和灰度图像）
            value = app.Slider_3.Value;

            % 检查图像是否加载
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, '请先加载一张图像。', '提示');
                return;
            end

            % 读取当前图像（兼容彩色/灰度）
            img = app.CurrentImage;

            % 判断是否为彩色图像（3通道）[6,8](@ref)
            if  size(img, 3) == 1
                
                % 已经是灰度图像，直接锐化
                sharpImg = imsharpen(img, 'Amount', (value/100)*2);
            end

            % 更新并显示图像
            app.CurrentImage = sharpImg;
            imshow(sharpImg, 'Parent', app.UIAxes);


        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            %调节分辨率
            Value = app.Slider_4.Value;

            % 确保原始图像已加载
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, '请先加载一张图像。', '提示');
                return;
            end

            % 从原始图像进行缩放（避免多次缩放产生失真）
            img = app.OriginalImage;

            % 缩放因子检查
            if Value <= 0
                uialert(app.UIFigure, '缩放比例必须大于0。', '错误');
                return;
            end

            % 计算缩放比例
            scale = Value / 100;

            % 获取原图尺寸
            [originalHeight, originalWidth, ~] = size(img);

            % 计算缩放后的尺寸（四舍五入确保为整数）
            newWidth = round(originalWidth * scale);
            newHeight = round(originalHeight * scale);

            % 使用目标尺寸进行缩放
            resizedImg = imresize(img, [newHeight, newWidth]);

            % 更新当前图像并显示
            app.CurrentImage = resizedImg;
            imshow(resizedImg, 'Parent', app.UIAxes);


        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, '当前没有图像可保存。', '保存失败');
                return;
            end

            % 定义目标文件夹路径
            targetFolder = 'E:\明月班项目\人脸\人脸集合\保存的人脸';

            % 检查并创建目标文件夹（如果不存在）
            if ~exist(targetFolder, 'dir')
                try
                    mkdir(targetFolder);  % 创建多级目录
                catch ME
                    uialert(app.UIFigure, ['无法创建目标文件夹: ', ME.message], '错误');
                    return;
                end
            end

            % 检查是否指定了文件名（假设app.image_path仅包含文件名）
            if isempty(app.image_path)
                defaultName = '未命名.png';  % 默认文件名
                savePath = fullfile(targetFolder, defaultName);
            else
                % 确保文件名包含扩展名（如未指定则默认PNG）
                [~, name, ext] = fileparts(app.image_path);
                if isempty(ext)
                    ext = '.png';  % 默认格式
                end
                savePath = fullfile(targetFolder, [name, ext]);
            end

            % 保存图像
            try
                imwrite(app.CurrentImage, savePath);
                uialert(app.UIFigure, ['图像已保存至: ', savePath], '保存成功');
            catch ME
                uialert(app.UIFigure, ['保存失败: ', ME.message], '错误');
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
        
        % 检查是否有图像加载
        if isempty(app.CurrentImage)
            app.Label2.Text = '请先加载图像';
            return;
        end
        
        % 获取图像信息
        [height, width, channels] = size(app.CurrentImage);
        
        % 获取图像类型
        imageType = class(app.CurrentImage);
        
        % 获取颜色深度
        if channels == 1
            colorDepth = '灰度图';
        else
            colorDepth = 'RGB彩色图';
        end
        
        % 获取文件格式
        if ~isempty(app.image_path)
            [~, ~, fileFormat] = fileparts(app.image_path);
        else
            fileFormat = '未知';
        end
        
        % 构建显示文本
        infoText = sprintf('图像大小: %d x %d\n颜色通道: %d\n颜色深度: %s\n数据类型: %s\n文件格式: %s', ...
            width, height, channels, colorDepth, imageType, fileFormat);
        
        % 更新Label2的文本
        app.Label2.Text = infoText;

        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
        % 检查是否有图像加载
        if isempty(app.gray_image) && isempty(app.CurrentImage)
            uialert(app.UIFigure, '请先加载一张图像。', '提示');
            return;
        end
        
        % 优先使用gray_image，如果没有则使用CurrentImage
        if ~isempty(app.gray_image)
            img = app.gray_image;
        else
            img = rgb2gray(app.CurrentImage); % 如果是彩色图转为灰度
        end
        
        % 清空UIAxes2并绘制灰度直方图
        cla(app.UIAxes2);
        histogram(app.UIAxes2, img(:), 'BinMethod', 'integers', 'FaceColor', [0.5 0.5 0.5]);
        title(app.UIAxes2, '灰度直方图');
        xlabel(app.UIAxes2, '灰度值');
        ylabel(app.UIAxes2, '像素数量');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)

            try
                % 检查是否有图像
                if isempty(app.CurrentImage)
                    msgbox('请先加载图像！', '错误', 'error');
                    return;
                end
                
                % 获取平移参数
                shift = str2double(app.TextArea.Value);
                if isnan(shift)
                    msgbox('请输入有效的数字！', '错误', 'error');
                    return;
                end
                
                % 使用circshift进行平移
                if ndims(app.CurrentImage) == 3  % 彩色图像
                    outputImage = circshift(app.CurrentImage, [0 shift 0]);
                else  % 灰度图像
                    outputImage = circshift(app.CurrentImage, [0 shift]);
                end
                
                % 更新图像显示
                app.CurrentImage = outputImage;
                imshow(outputImage, 'Parent', app.UIAxes);
            catch e
                msgbox(['平移操作失败：' e.message], '错误', 'error');
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            % 获取TextArea_3中的数值作为缩放比例
            scaleText = app.TextArea_3.Value;
            
            % 将文本转换为数值
            scale = str2double(scaleText);
            
            % 检查是否成功转换
            if isnan(scale)
                uialert(app.UIFigure, '请输入有效的数字！', '错误');
                return;
            end
            
            % 检查是否有图像加载
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, '请先加载图像！', '错误');
                return;
            end
            
            % 计算缩放比例（输入值除以100）
            scaleFactor = scale / 100;
            
            % 使用imresize函数对当前图像进行缩放
            resizedImage = imresize(app.CurrentImage, scaleFactor);
            
            % 在axes1中显示缩放后的图像
            cla(app.UIAxes);
            imshow(resizedImage, 'Parent', app.UIAxes);
            
            % 更新当前图像
            app.CurrentImage = resizedImage;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            % 检查是否有图像加载
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, '请先加载图像！', '提示');
                return;
            end

            % 使用高斯滤波进行去噪
            sigma = 1.5; % 高斯核标准差
            denoised_image = imgaussfilt(app.CurrentImage, sigma);

            % 显示去噪后的图像
            cla(app.UIAxes);
            imshow(denoised_image, 'Parent', app.UIAxes);

            % 更新当前图像
            app.CurrentImage = denoised_image;
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
                        % 检查是否有图像加载
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, '请先加载图像！', '提示');
                return;
            end
            
            % 获取当前图像
            img = app.CurrentImage;
            
            % 调整图像尺寸为353×353
            resizedImg = imresize(img, [353 353]);
            
            % 更新显示
            app.CurrentImage = resizedImg;
            imshow(resizedImg, 'Parent', app.UIAxes);
            
            % 更新图像信息
            app.Label2.Text = sprintf('图像大小: %d × %d', 353, 353);
            
            figure(app.UIFigure);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            % 检查是否有图像加载
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, '请先加载图像！', '错误');
                return;
            end

            % 获取当前显示的图像数据
            img = app.CurrentImage;

            % 转换为灰度图像
            if size(img, 3) == 3
                grayImg = rgb2gray(img);
            else
                grayImg = img;
            end

            % 检测人脸
            faceDetector = vision.CascadeObjectDetector();
            bbox = step(faceDetector, grayImg);

            if isempty(bbox)
                uialert(app.UIFigure, '未检测到人脸！', '提示');
                return;
            end
            % 只取第一个人脸
            face = bbox(1, :);
            % 检测眼睛（用于配准）
            eyeDetector = vision.CascadeObjectDetector('EyePairBig');
            eyeBBox = step(eyeDetector, grayImg);

            if isempty(eyeBBox)
                uialert(app.UIFigure, '未检测到眼睛，无法配准！', '提示');
                return;
            end
            % 取检测到的第一个眼睛区域
            eyes = eyeBBox(1, :);
            % 计算两眼中心点
            eyeCenter = [eyes(1) + eyes(3)/2, eyes(2) + eyes(4)/2];
            % 目标模板（标准位置），假设配准后眼睛中心在新图像的中心上方1/3处
            targetSize = [1024, 1024]; % 目标输出大小
            targetEyeCenter = [targetSize(2)/2, targetSize(1)/3];
            % 计算仿射变换（只用平移和缩放，避免异常）
            scale = (targetSize(2)*0.4) / eyes(3); % 目标宽度为原眼距的0.4倍
            tform = affine2d([scale 0 0; 0 scale 0; ...
                targetEyeCenter(1)-eyeCenter(1)*scale, ...
                targetEyeCenter(2)-eyeCenter(2)*scale, 1]);
            % 应用仿射变换
            outputImage = imwarp(img, tform, 'OutputView', imref2d(targetSize));
            % 显示配准后图像
            imshow(outputImage, 'Parent', app.UIAxes);
            app.CurrentImage = outputImage;
        end

        % Callback function
        function Button_20Pushed(app, event)
            
        end

        % Callback function
        function Button_21Pushed(app, event)
           
        end

        % Button pushed function: PCAButton
        function PCAButtonPushed(app, event)
            trainPath = uigetdir('选择训练集文件夹');
            trainFiles = dir(fullfile(trainPath, '*.jpg')); % 假设是jpg格式
            numTrain = length(trainFiles);
            img = imread(fullfile(trainPath, trainFiles(1).name));
            [h, w] = size(img);
            app.trainData = zeros(h*w, numTrain);

            for i = 1:numTrain
                img = im2double(imread(fullfile(trainPath, trainFiles(i).name)));
                app.trainData(:, i) = img(:); % 将图像转为列向量
            end

            % 2. 计算平均脸
            app.meanFace = mean(app.trainData, 2);
            % 显示在app.UIAxes4上
            imshow(reshape(app.meanFace, [h, w]), 'Parent', app.UIAxes4);
            title(app.UIAxes4, '平均脸');

            % 3. 计算差值脸
            app.diffFaces = app.trainData - app.meanFace;

            % 4. 构建协方差矩阵 (使用简化计算)
            L = app.diffFaces' * app.diffFaces; % 小矩阵技巧
            [V, D] = eig(L);
            eigenvalues = diag(D);
            [~, idx] = sort(eigenvalues, 'descend');
            V = V(:, idx);

            % 5. 构建特征向量空间
            app.eigenfaces = app.diffFaces * V; % 转换为大矩阵特征向量
            for i = 1:size(app.eigenfaces, 2)
                app.eigenfaces(:, i) = app.eigenfaces(:, i)/norm(app.eigenfaces(:, i));
            end
            app.contributionRate = app.Slider_5.Value;
            % 6. 选择前P个主成分 (贡献率可自由调节)
            totalEnergy = sum(eigenvalues);
            energy = 0;
            P = 0;
            while energy/totalEnergy < app.contributionRate && P < numTrain
                P = P + 1;
                energy = energy + eigenvalues(idx(P));
            end
            app.eigenfaces = app.eigenfaces(:, 1:P);

            % 7. 投影训练图像到特征空间
            omegaTrain = app.eigenfaces' * app.diffFaces;

            % 读取测试集
            testPath = uigetdir('选择测试集文件夹');
            testFiles = dir(fullfile(testPath, '*.jpg'));
            numTest = length(testFiles);

            for k = 1:numTest
                % 8. 处理测试图像
                testImg = im2double(imread(fullfile(testPath, testFiles(k).name)));
                diffTest = testImg(:) - app.meanFace;
                omegaTest = app.eigenfaces' * diffTest;

                % 10. 计算欧式距离
                distances = zeros(1, numTrain);
                for i = 1:numTrain
                    distances(i) = norm(omegaTest - omegaTrain(:, i));
                end

                % 11. 找出最相似的图像
                [~, minIdx] = min(distances);
                matchedImg = reshape(app.trainData(:, minIdx), [h, w]);

                % 显示结果到指定的UIAxes组件
                % 测试图像显示在app.UIAxes3
                imshow(testImg, 'Parent', app.UIAxes3);
                title(app.UIAxes3, '测试图像');

                % 匹配图像显示在app.UIAxes5
                imshow(matchedImg, 'Parent', app.UIAxes5);
                title(app.UIAxes5, ['匹配图像 (距离=', num2str(min(distances)), ')']);

                % 差值脸显示在app.UIAxes6
                imshow(reshape(diffTest, [h, w]), 'Parent', app.UIAxes6);
                title(app.UIAxes6, '特征脸');
                pause(1); % 暂停1秒观察结果
                drawnow
            end
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.contributionRate = app.Slider_5.Value;
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            [file, path] = uigetfile('*.mat', '选择线性核文件');
            load(fullfile(path, file));
            figure('Name', '人脸识别系统', 'NumberTitle', 'off', 'Position', [100, 100, 1200, 600]);
            subplot(2,2,1);
            imshow(reshape(uint8(meanFeature), imgSize), []); title('平均脸');

            try
                % 列出可用的摄像头
                camList = webcamlist;
                if isempty(camList)
                    error('未检测到可用的摄像头设备');
                end

                % 显示可用的摄像头列表
                disp('可用的摄像头设备：');
                for i = 1:length(camList)
                    disp(sprintf('%d. %s', i, camList{i}));
                end

                % 使用默认摄像头
                cam = webcam;
                disp('摄像头已启动，按Ctrl+C停止识别');

                % 测试摄像头是否正常工作
                testImg = snapshot(cam);
                if isempty(testImg)
                    error('摄像头无法捕获图像');
                end

            catch ME
                switch ME.identifier
                    case 'MATLAB:webcam:noDevice'
                        error('未检测到可用的摄像头设备，请检查摄像头连接');
                    case 'MATLAB:webcam:deviceInUse'
                        error('摄像头正被其他程序占用，请关闭其他使用摄像头的程序');
                    otherwise
                        error('摄像头初始化失败：%s', ME.message);
                end
            end

            % 9. 实时识别
            % 创建人脸检测器
            faceDetector = vision.CascadeObjectDetector();
            faceDetector.MergeThreshold = 4;  % 调整检测灵敏度

            % 设置界面显示
            subplot(2,2,2);
            h1 = imshow(zeros(size(testImg))); title('原始摄像头画面');


            % 创建结果显示文本
            resultText = uicontrol('Style', 'text', ...
                'Position', [400, 20, 400, 50], ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'HorizontalAlignment', 'center');

            % 创建状态显示文本
            statusText = uicontrol('Style', 'text', ...
                'Position', [400, 80, 400, 30], ...
                'FontSize', 12, ...
                'HorizontalAlignment', 'center', ...
                'String', '正在运行...');

            % 定义是否正在运行的标志
            isRunning = true;

            % 添加计时和结果统计变量
            startTime = tic;
            resultCounts = containers.Map('KeyType', 'char', 'ValueType', 'any');
            lastDisplayTime = 0;

            while isRunning
                try
                    % 捕获图像
                    img = snapshot(cam);

                    % 显示原始摄像头画面（添加镜面翻转）
                    imgFlipped = fliplr(img); % 镜像翻转，便于用户操作

                    % 显示原始摄像头画面
                    set(h1, 'CData', imgFlipped);
                    detector = vision.CascadeObjectDetector();
                    detector.MergeThreshold = 5;  % 调整检测灵敏度
                    % 人脸检测
                    bbox = step(detector, img);
                    if isempty(bbox)
                        error('未检测到人脸');
                    end

                    % 在原始图像上检测人脸
                    bbox = step(faceDetector, imgFlipped);

                    if ~isempty(bbox)
                        % 获取最大的人脸（通常是最近的人脸）
                        [~, idx] = max(bbox(:,3) .* bbox(:,4));
                        faceBox = bbox(idx, :);

                        % 在原始图像上标记人脸
                        imgWithBox = insertShape(imgFlipped, 'Rectangle', faceBox, 'LineWidth', 3, 'Color', 'green');
                        set(h1, 'CData', imgWithBox);

                        % 提取人脸区域
                        faceImg = imcrop(imgFlipped, faceBox);

                        % 转换为灰度
                        if size(faceImg, 3) == 3
                            grayFace = rgb2gray(faceImg);
                        else
                            grayFace = faceImg;
                        end

                        % 调整大小
                        processedImg = imresize(grayFace, imgSize);

                        % 添加图像预处理步骤
                        % 1. 直方图均衡化
                        processedImg = histeq(processedImg);

                        % 2. 高斯滤波 - 使用更小的标准差以减少模糊
                        processedImg = imgaussfilt(processedImg, 0.3);

                        % 4. 添加自适应直方图均衡化
                        processedImg = adapthisteq(processedImg);

                        testFeat = double(processedImg(:))';
                        % 图像预处理
                        testVec = testFeat - meanFeature;
                        testScore = testVec * coeff;
                        testReduced = testScore(1:numPCAComponents);
                        predictedLabel = predict(svmModel, testReduced);

                        % 统计结果
                        currentLabel = char(predictedLabel);
                        if isKey(resultCounts, currentLabel)
                            resultCounts(currentLabel) = resultCounts(currentLabel) + 1;
                        else
                            resultCounts(currentLabel) = 1;
                        end

                        currentTime = toc(startTime);
                        if currentTime - lastDisplayTime >= 3
                            % 找出出现次数最多的结果
                            if ~isempty(resultCounts)
                                [~, maxIdx] = max(cell2mat(values(resultCounts)));
                                mostFrequentLabel = keys(resultCounts);
                                mostFrequentLabel = mostFrequentLabel{maxIdx};

                                % 更新显示
                                set(resultText, 'String', sprintf('识别结果: %s', mostFrequentLabel));
                                set(statusText, 'String', '识别成功', 'ForegroundColor', [0 0.7 0]);
                                fprintf('✅ 预测结果：%s\n', mostFrequentLabel);
                            end

                            % 重置统计
                            resultCounts = containers.Map('KeyType', 'char', 'ValueType', 'any');
                            lastDisplayTime = currentTime;
                        end
                    else
                        % 未检测到人脸
                        set(statusText, 'String', '未检测到人脸，请调整位置', 'ForegroundColor', [0.8 0 0]);
                        % 清空识别结果
                        set(resultText, 'String', '');
                    end
                    drawnow;
                    pause(0.1); % 短暂暂停，减少CPU使用

                catch ME
                    if strcmp(ME.identifier, 'MATLAB:class:InvalidHandle')
                        break;
                    else
                        set(statusText, 'String', ['识别出错: ' ME.message], 'ForegroundColor', [0.8 0 0]);
                        disp(['识别出错: ' ME.message]);
                    end
                end
            end

            % 程序结束时释放摄像头资源
            try
                clear cam;
                disp('摄像头已关闭');
            catch
                % 忽略释放资源时的错误
            end
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
             %打开文件
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Select an Image');

            % 检查用户是否取消选择
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            % 构建完整文件路径
            fullPath = fullfile(pathname, filename);

            try
                img = imread(fullPath);
                app.image_path=fullPath;
                imshow(img, 'Parent', app.UIAxes3);  % 显示到名为UIAxes的坐标轴组件
                app.CurrentImage = img;
                app.OriginalImage=img;
                app.Slider.Value = 50;
                app.Slider_2.Value = 50;

                % 存储原始图像

            catch ME
                errordlg(['Error: ', ME.message], 'Image Load Failed');
            end
            figure(app.UIFigure);
        end

        % Value changed function: Button_24
        function Button_24ValueChanged(app, event)
             
            value = app.Button_24.Value;

            % 获取用户输入的文本（按行分割）
            userText = strsplit(app.EditField.Value, '\n');

            % 读取图像并初始化参数
            frame = getframe(app.UIAxes3);
            background = frame.cdata;
            butterfly = imread("E:\明月班项目\人脸识别项目文件\matapp\matapp\R-C_副本.png");
            [bg_h, bg_w, ~] = size(background);
            num_butterflies = 52;
            scale_range = [0.02, 0.05];
            scales = linspace(scale_range(1), scale_range(2), num_butterflies);
            a = min(bg_h, bg_w)/6.5; % 心形大小系数

            % 平移参数设置
            offset_x = 3;
            offset_y = -50;

            % 数据预处理
            background = im2double(background);
            butterfly = im2double(butterfly);

            % 生成心形轨迹点
            t = linspace(0, 2*pi, num_butterflies+1);
            t = t(1:end-1);
            x = a*(2*cos(t) - cos(2*t));
            y = a*(2*sin(t) - sin(2*t));

            % 旋转90°并平移
            x_pos = y + bg_w/2 + offset_x;
            y_pos = -x + bg_h/2 + offset_y;

            % 创建图形窗口（1行3列布局，中间留空放文本）
            figure;
            set(gcf, 'Position', [100 100 1200 500]); % 增大窗口宽度

            % 左侧子图（顺时针）
            subplot(1,3,1);
            h_im1 = imshow(background);
            title('顺时针旋转');
            hold on;
            h_plot1 = plot(x_pos, y_pos, 'r-', 'LineWidth', 1);
            scatter(x_pos, y_pos, 30, 'r', 'filled');

            % 放置第一组蝴蝶
            for i = 1:num_butterflies
                scaled_butterfly = imresize(butterfly, scales(i));
                butterfly_pos = [x_pos(i)-size(scaled_butterfly,2)/2, y_pos(i)-size(scaled_butterfly,1)/2];
                h_butterflies1(i) = image(butterfly_pos(1), butterfly_pos(2), scaled_butterfly);
            end
            hold off;

            % 中间文本区域 - 使用用户输入的文本
            subplot(1,3,2);
            axis off;
            text(0.5, 0.5, userText, ...
                'FontSize', 24, ...
                'FontWeight', 'bold', ...
                'Color', [1 0 0], ...
                'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle');

            % 右侧子图（逆时针）
            subplot(1,3,3);
            h_im2 = imshow(background);
            title('逆时针旋转');
            hold on;
            h_plot2 = plot(x_pos, y_pos, 'r-', 'LineWidth', 1);
            scatter(x_pos, y_pos, 30, 'r', 'filled');

            % 放置第二组蝴蝶
            for i = 1:num_butterflies
                scaled_butterfly = imresize(butterfly, scales(i));
                butterfly_pos = [x_pos(i)-size(scaled_butterfly,2)/2, y_pos(i)-size(scaled_butterfly,1)/2];
                h_butterflies2(i) = image(butterfly_pos(1), butterfly_pos(2), scaled_butterfly);
            end
            hold off;

            % 播放背景音乐
            [y, Fs] = audioread("E:\明月班项目\人脸识别项目文件\matapp\matapp\庞龙 你是我的玫瑰 _爱给网_aigei_com.mp3");
            player = audioplayer(y(1:min(end,9*Fs)), Fs);
            play(player);

            % 旋转动画参数
            rotation_angles = 0:5:360;
            frame_delay = 0.05;

            % 旋转动画循环
            for angle = rotation_angles
                % 顺时针旋转（左图）
                rotated_bg1 = imrotate(background, angle, 'bilinear', 'crop');
                set(h_im1, 'CData', rotated_bg1);

                % 逆时针旋转（右图）
                rotated_bg2 = imrotate(background, -angle, 'bilinear', 'crop');
                set(h_im2, 'CData', rotated_bg2);

                % 更新蝴蝶位置
                for i = 1:num_butterflies
                    % 顺时针蝴蝶
                    x_rot1 = a*(2*cos(t(i)+deg2rad(angle)) - cos(2*(t(i)+deg2rad(angle))));
                    y_rot1 = a*(2*sin(t(i)+deg2rad(angle)) - sin(2*(t(i)+deg2rad(angle))));
                    x_pos_rot1 = y_rot1 + bg_w/2 + offset_x;
                    y_pos_rot1 = -x_rot1 + bg_h/2 + offset_y;

                    % 逆时针蝴蝶
                    x_rot2 = a*(2*cos(t(i)-deg2rad(angle)) - cos(2*(t(i)-deg2rad(angle))));
                    y_rot2 = a*(2*sin(t(i)-deg2rad(angle)) - sin(2*(t(i)-deg2rad(angle))));
                    x_pos_rot2 = y_rot2 + bg_w/2 + offset_x;
                    y_pos_rot2 = -x_rot2 + bg_h/2 + offset_y;

                    scaled_butterfly = imresize(butterfly, scales(i));
                    set(h_butterflies1(i), 'XData', x_pos_rot1-size(scaled_butterfly,2)/2, ...
                        'YData', y_pos_rot1-size(scaled_butterfly,1)/2);
                    set(h_butterflies2(i), 'XData', x_pos_rot2-size(scaled_butterfly,2)/2, ...
                        'YData', y_pos_rot2-size(scaled_butterfly,1)/2);
                end

                drawnow;
                pause(frame_delay);
            end

            % 等待音乐播放完成
            while isplaying(player)
                pause(0.1);
            end

            
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
               imgHandles = findobj(app.UIAxes3, 'Type', 'image');

            % 删除图像对象
            if ~isempty(imgHandles)
                cla(app.UIAxes3);
                reset(app.UIAxes3);  % 重置坐标轴属性
                title(app.UIAxes3, '');  % 清除标题  % 仅删除图像，保留其他图形
            end
            figure(app.UIFigure);
            imgHandles2 = findobj(app.UIAxes4, 'Type', 'image');

            % 删除图像对象
            if ~isempty(imgHandles2)
                cla(app.UIAxes4);
                reset(app.UIAxes4);  % 重置坐标轴属性
                title(app.UIAxes4, '');  % 清除标题  % 仅删除图像，保留其他图形
            end
            figure(app.UIFigure);
            imgHandles3 = findobj(app.UIAxes5, 'Type', 'image');

            % 删除图像对象
            if ~isempty(imgHandles3)
                cla(app.UIAxes5);
                reset(app.UIAxes5);  % 重置坐标轴属性
                title(app.UIAxes5, '');  % 清除标题  % 仅删除图像，保留其他图形
            end

            figure(app.UIFigure);
            imgHandles4 = findobj(app.UIAxes6, 'Type', 'image');

            % 删除图像对象
            if ~isempty(imgHandles4)
                cla(app.UIAxes6);
                reset(app.UIAxes6);  % 重置坐标轴属性
                title(app.UIAxes6, '');  % 清除标题  % 仅删除图像，保留其他图形
            end
            figure(app.UIFigure);


        end

        % Callback function
        function train_svm(app, event)

        end

        % Button pushed function: Button_28
        function TrainButtonPushed(app, event)
            % ==== 参数设置 ====
            datasetPath = "E:\明月班项目\人脸识别项目文件\matapp\matapp\for_svm\for_svm";  % 替换为你的路径
            imgSize = [335, 335];
            numPCAComponents = 50;
            modelFile = 'svm_face_model_dynamic.mat';

            % ==== 加载图像数据 ====
            peopleFolders = dir(datasetPath);
            peopleFolders = peopleFolders([peopleFolders.isdir] & ~startsWith({peopleFolders.name}, '.'));

            features = [];
            labels = [];
            for i = 1:length(peopleFolders)
                personName = peopleFolders(i).name;
                personPath = fullfile(datasetPath, personName);
                imgFiles = dir(fullfile(personPath, '*.jpg'));
                for j = 1:length(imgFiles)
                    imgPath = fullfile(personPath, imgFiles(j).name);
                    img = imread(imgPath);
                    if size(img,3) == 3
                        img = rgb2gray(img);
                    end
                    img = imresize(img, imgSize);
                    feat = double(img(:))';
                    features = [features; feat];
                    labels = [labels; {personName}];
                end
            end
            labels = categorical(labels);

            % ==== PCA 降维 ====
            meanFeature = mean(features, 1);
            centeredFeatures = features - meanFeature;
            [coeff, score, ~] = pca(centeredFeatures);
            reducedFeatures = score(:, 1:numPCAComponents);

            % ==== 提取用户输入的参数 ====
            kernelType = app.SelectedKernelType;  % 你需要在app中创建这个属性并在按钮点击时更新
            boxC = str2double(app.BoxConstraintEditField.Value);
            scale = str2double(app.KernelScaleEditField.Value);
            polyOrder = str2double(app.PolynomialOrderEditField.Value);

            if isnan(boxC) || boxC <= 0
                boxC = 1; % 默认
            end
            if isnan(scale) || scale <= 0
                scale = 'auto';
            end

            % ==== 根据核函数选择创建模板 ====
            switch kernelType
                case 'linear'
                    t = templateSVM('KernelFunction', 'linear', ...
                        'BoxConstraint', boxC, ...
                        'Standardize', true);
                case 'rbf'
                    t = templateSVM('KernelFunction', 'rbf', ...
                        'KernelScale', scale, ...
                        'BoxConstraint', boxC, ...
                        'Standardize', true);
                case 'polynomial'
                    if isnan(polyOrder) || polyOrder <= 0
                        polyOrder = 2;
                    end
                    t = templateSVM('KernelFunction', 'polynomial', ...
                        'PolynomialOrder', polyOrder, ...
                        'KernelScale', scale, ...
                        'BoxConstraint', boxC, ...
                        'Standardize', true);
                otherwise
                    uialert(app.UIFigure, '请选择有效的核函数类型', '错误');
                    return;
            end

            % ==== 训练模型 ====
            app.Button_28.Text = '训练中...';
            drawnow;
            try
                svmModel = fitcecoc(reducedFeatures, labels, 'Learners', t, ...
                    'Coding', 'onevsall', ...
                    'Verbose', 1);
                save(modelFile, 'svmModel', 'meanFeature', 'coeff', 'numPCAComponents', 'imgSize');
                uialert(app.UIFigure, '训练完成，模型已保存。', '成功');
            catch ME
                uialert(app.UIFigure, ['训练失败: ' ME.message], '错误');
            end
            app.Button_28.Text = '训练模型并保存';
        end

        % Button pushed function: Button_29
        function LinearKernelButtonPushed(app, event)
            app.SelectedKernelType = 'linear';
        end

        % Button pushed function: RBFButton
        function RBFKernelButtonPushed(app, event)
            app.SelectedKernelType = 'rbf';
        end

        % Button pushed function: Button_30
        function PolynomialKernelButtonPushed(app, event)
            app.SelectedKernelType = 'polynomial';
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            % 1. 读取训练集图像数据
            trainPath = uigetdir('选择训练集文件夹');
            trainFiles = dir(fullfile(trainPath, '*.jpg')); % 假设是jpg格式
            numTrain = length(trainFiles);
            img = imread(fullfile(trainPath, trainFiles(1).name));
            [h, w] = size(img);
            trainData = zeros(h*w, numTrain);

            for i = 1:numTrain
                img = im2double(imread(fullfile(trainPath, trainFiles(i).name)));
                trainData(:, i) = img(:); % 将图像转为列向量
            end

            % 保存变量到.mat文件
            save('PCA_Part1_Output.mat', 'trainData', 'h', 'w','numTrain', '-v7.3');

            % 用figure显示保存成功信息（替换原图像显示）
            f = figure('Name', '环节1完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', '变量已保存为PCA_Part1_Output.mat', ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
             % 2. 计算平均脸
            [file, path] = uigetfile('*.mat', '选择PCA_Part1_Output.mat文件');
            load(fullfile(path, file));

            % 计算平均脸
            meanFace = mean(trainData, 2);

            % 保存变量
            save('PCA_Part2_Output.mat', 'meanFace', 'trainData', 'h', 'w', 'numTrain', '-v7.3');

            % 创建图形界面显示保存成功信息
            f = figure('Name', '环节2完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', '变量已保存为PCA_Part2_Output.mat', ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: Button_33
        function Button_33Pushed(app, event)
            % 3. 计算差值脸
            [file, path] = uigetfile('*.mat', '选择PCA_Part2_Output.mat文件');
            load(fullfile(path, file));

            % 计算差值脸
            diffFaces = trainData - meanFace;

            % 保存变量
            save('PCA_Part3_Output.mat', 'diffFaces', 'meanFace', 'h', 'w', 'trainData', 'numTrain', '-v7.3');

            % 创建图形界面显示保存成功信息
            f = figure('Name', '环节3完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', '变量已保存为PCA_Part3_Output.mat', ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
             % 4. 构建协方差矩阵
            [file, path] = uigetfile('*.mat', '选择PCA_Part3_Output.mat文件');
            load(fullfile(path, file));

            % 计算小矩阵技巧的协方差矩阵
            L = diffFaces' * diffFaces; % 小矩阵技巧[7](@ref)
            [V, D] = eig(L);
            eigenvalues = diag(D);
            [~, idx] = sort(eigenvalues, 'descend');
            V = V(:, idx);

            % 保存变量
            save('PCA_Part4_Output.mat', 'V', 'D', 'idx', 'diffFaces', 'meanFace', 'h', 'w', 'trainData','eigenvalues','numTrain',  '-v7.3');

            % 创建图形界面显示保存成功信息
            f = figure('Name', '环节4完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', '变量已保存为PCA_Part4_Output.mat', ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            % 5. 构建特征向量空间
            [file, path] = uigetfile('*.mat', '选择PCA_Part4_Output.mat文件');
            load(fullfile(path, file));

            % 计算特征脸（大矩阵特征向量）
            eigenfaces = diffFaces * V;
            for i = 1:size(eigenfaces, 2)
                eigenfaces(:, i) = eigenfaces(:, i) / norm(eigenfaces(:, i)); % 单位化
            end

            % 保存变量
            save('PCA_Part5_Output.mat', 'eigenfaces', 'diffFaces', 'meanFace', 'h', 'w', 'trainData', 'eigenvalues','numTrain', 'idx','-v7.3');

            % 创建图形界面显示保存成功信息
            f = figure('Name', '环节5完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', '变量已保存为PCA_Part5_Output.mat', ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: P85Button
        function P85ButtonPushed(app, event)
             % 6. 选择前P个主成分
            [file, path] = uigetfile('*.mat', '选择PCA_Part5_Output.mat文件');
            load(fullfile(path, file));

            % 计算特征值总和
            
            totalEnergy = sum(eigenvalues);

            % 选择贡献率>85%的主成分
            energy = 0;
            P = 0;
            while energy/totalEnergy < 0.85 && P < numTrain
                P = P + 1;
                energy = energy + eigenvalues(idx(P));
            end
            eigenfaces = eigenfaces(:, 1:P);

            % 保存变量
            save('PCA_Part6_Output.mat', 'eigenfaces', 'P', 'diffFaces', 'meanFace', 'h', 'w', 'trainData', '-v7.3');

            % 创建图形界面显示保存成功信息
            f = figure('Name', '环节6完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', ['变量已保存为PCA_Part6_Output.mat (P=', num2str(P), ')'], ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: Button_36
        function Button_36Pushed(app, event)
            % 7. 投影训练图像到特征空间
            [file, path] = uigetfile('*.mat', '选择PCA_Part6_Output.mat文件');
            load(fullfile(path, file));

            % 计算训练样本在特征空间的投影
            omegaTrain = eigenfaces' * diffFaces;

            % 保存变量
            save('PCA_Part7_Output.mat', 'omegaTrain', 'eigenfaces', 'meanFace', 'h', 'w', 'trainData', '-v7.3');

            % 创建图形界面显示保存成功信息
            f = figure('Name', '环节7完成', 'NumberTitle', 'off', 'MenuBar', 'none');
            uicontrol(f, 'Style', 'text', ...
                'String', '变量已保存为PCA_Part7_Output.mat', ...
                'Units', 'normalized', 'Position', [0.1, 0.4, 0.8, 0.2], ...
                'FontSize', 14, 'HorizontalAlignment', 'center');
            uicontrol(f, 'Style', 'pushbutton', ...
                'String', '确定', ...
                'Units', 'normalized', 'Position', [0.4, 0.2, 0.2, 0.1], ...
                'Callback', 'close(gcf)');
            waitfor(f); % 等待用户关闭窗口
        end

        % Button pushed function: Button_37
        function Button_37Pushed(app, event)
             % 8. 测试集人脸识别
            [file, path] = uigetfile('*.mat', '选择PCA_Part7_Output.mat文件');
            load(fullfile(path, file));

            % 读取测试集
            testPath = uigetdir('选择测试集文件夹');
            testFiles = dir(fullfile(testPath, '*.jpg'));
            numTest = length(testFiles);

            % 创建图窗显示结果
            resultFig = figure;
            set(resultFig, 'Position', [100, 100, 800, 600]);

            for k = 1:numTest
                % 处理测试图像
                testImg = im2double(imread(fullfile(testPath, testFiles(k).name)));
                diffTest = testImg(:) - meanFace;
                omegaTest = eigenfaces' * diffTest;

                % 计算欧式距离
                distances = zeros(1, size(omegaTrain, 2));
                for i = 1:size(omegaTrain, 2)
                    distances(i) = norm(omegaTest - omegaTrain(:, i));
                end

                % 找出最相似的图像
                [~, minIdx] = min(distances);
                matchedImg = reshape(trainData(:, minIdx), [h, w]);

                % 显示结果
                figure(resultFig);
                subplot(2, 2, 1); imshow(testImg); title('测试图像');
                subplot(2, 2, 2); imshow(matchedImg);
                title(['匹配图像 (距离=', num2str(min(distances)), ')']);
                subplot(2, 2, 3); imshow(reshape(diffTest, [h, w])); title('差值脸');
                subplot(2, 2, 4);
                plot(1:size(omegaTrain,2), distances, 'b.', minIdx, min(distances), 'ro');
                title('距离分布'); xlabel('训练样本'); ylabel('欧式距离');

                pause(1); % 暂停1秒观察结果
            end

        end

        % Callback function
        function Slider_6ValueChanged(app, event)
            
            app.contributionRate2 = app.Slider_6.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像处理';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '349.png');
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '主要功能';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '打开文件';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清除文件';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '颜色处理';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '灰度化';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '均衡化';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '亮度';
            app.Slider = uislider(app.Panel_2);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '图像显示选择';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'自动', '原图', '新图'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '自动';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '对比度';
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '加强边缘清晰度';
            app.Slider_3 = uislider(app.Panel);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '分辨率';
            app.Slider_4 = uislider(app.Panel);
            app.Slider_4.Limits = [0 10];
            app.Slider_4.MajorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250 260 270 280 290 300];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Value = 1;
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '保存';
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '图像处理工具箱';
            app.UIAxes2 = uiaxes(app.Panel_4);
            title(app.UIAxes2, '灰度直方图')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button_6 = uibutton(app.Panel_4, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '获取图像参数';
            app.Button_7 = uibutton(app.Panel_4, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '绘制灰度直方图';
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = '图像变换功能';
            app.Button_8 = uibutton(app.Panel_6, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '图像平移';
            app.Button_10 = uibutton(app.Panel_6, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '图像缩放';
            app.Label_9 = uilabel(app.Panel_6);
            app.Label_9.Text = '平移参数';
            app.TextArea = uitextarea(app.Panel_6);
            app.Label_11 = uilabel(app.Panel_6);
            app.Label_11.Text = '缩放参数';
            app.TextArea_3 = uitextarea(app.Panel_6);
            app.Button_17 = uibutton(app.Panel_6, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '一键配准';
            app.Button_18 = uibutton(app.Panel_6, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '尺寸统一';
            app.Button_19 = uibutton(app.Panel_6, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '去噪';
            app.Panel_5 = uipanel(app.Panel_4);
            app.Panel_5.Title = '参数：';
            app.Label2 = uilabel(app.Panel_5);
            app.Label2.Text = 'Label2';
            app.Panel_7 = uipanel(app.Tab);
            app.Panel_7.Title = '图像显示';
            app.UIAxes = uiaxes(app.Panel_7);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_13 = uilabel(app.Tab);
            app.Label_13.Text = '图像名称';
            app.Label_14 = uilabel(app.Tab);
            app.Label_14.Text = '修改时间';
            app.Label_15 = uilabel(app.Tab);
            app.Label_15.Text = '报错信息';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '人脸识别';
            app.Panel_8 = uipanel(app.Tab_2);
            app.Panel_8.Title = '操作界面';
            app.Button_22 = uibutton(app.Panel_8, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '启动摄像头';
            app.PCAButton = uibutton(app.Panel_8, 'push');
            app.PCAButton.ButtonPushedFcn = createCallbackFcn(app, @PCAButtonPushed, true);
            app.PCAButton.Text = '启动PCA';
            app.Label_16 = uilabel(app.Panel_8);
            app.Label_16.Text = '贡献率';
            app.Slider_5 = uislider(app.Panel_8);
            app.Slider_5.Limits = [0 1];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Button_23 = uibutton(app.Panel_8, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '加载文件';
            app.Button_24 = uibutton(app.Panel_8, 'state');
            app.Button_24.ValueChangedFcn = createCallbackFcn(app, @Button_24ValueChanged, true);
            app.Button_24.Text = '创意小功能';
            app.Label_17 = uilabel(app.Panel_8);
            app.Label_17.Text = '想说的';
            app.EditField = uieditfield(app.Panel_8, 'text');
            app.Button_25 = uibutton(app.Panel_8, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '清除全部';
            app.Panel_10 = uipanel(app.Tab_2);
            app.Panel_10.Title = '图像显示';
            app.UIAxes3 = uiaxes(app.Panel_10);
            title(app.UIAxes3, '输入图像')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Panel_10);
            title(app.UIAxes4, '平均脸')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.Panel_10);
            title(app.UIAxes5, '匹配的人脸')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Panel_11 = uipanel(app.Tab_2);
            app.Panel_11.Title = '特征脸';
            app.UIAxes6 = uiaxes(app.Panel_11);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Image5 = uiimage(app.Tab_2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '310c821797e70e10929e34a32587f2b4.gif');
            app.Image6 = uiimage(app.Tab_2);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '310c821797e70e10929e34a32587f2b4.gif');
            app.Image7 = uiimage(app.Tab_2);
            app.Image7.ImageSource = fullfile(pathToMLAPP, '310c821797e70e10929e34a32587f2b4.gif');
            app.Image8 = uiimage(app.Tab_2);
            app.Image8.ImageSource = fullfile(pathToMLAPP, '310c821797e70e10929e34a32587f2b4.gif');
            app.Image9 = uiimage(app.Tab_2);
            app.Image9.ImageSource = fullfile(pathToMLAPP, '310c821797e70e10929e34a32587f2b4.gif');
            app.Image10 = uiimage(app.Tab_2);
            app.Image10.ImageSource = fullfile(pathToMLAPP, '1539430ae1df267344504df26ef6c01e.gif');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '训练过程';
            app.Image11 = uiimage(app.Tab_3);
            app.Image11.ImageSource = fullfile(pathToMLAPP, '【哲风壁纸】清晨-雪山.png');
            app.BoxConstraintEditFieldLabel = uilabel(app.Tab_3);
            app.BoxConstraintEditFieldLabel.Text = 'BoxConstraint';
            app.BoxConstraintEditField = uieditfield(app.Tab_3, 'numeric');
            app.TextArea_5 = uitextarea(app.Tab_3);
            app.TextArea_5.Value = {'参数调整'};
            app.KernelScaleEditFieldLabel = uilabel(app.Tab_3);
            app.KernelScaleEditFieldLabel.Text = 'KernelScale';
            app.KernelScaleEditField = uieditfield(app.Tab_3, 'numeric');
            app.PolynomialOrderLabel = uilabel(app.Tab_3);
            app.PolynomialOrderLabel.Text = 'PolynomialOrder';
            app.PolynomialOrderEditField = uieditfield(app.Tab_3, 'text');
            app.Button_28 = uibutton(app.Tab_3, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.Button_28.Text = '训练模型并保存';
            app.RBFButton = uibutton(app.Tab_3, 'push');
            app.RBFButton.ButtonPushedFcn = createCallbackFcn(app, @RBFKernelButtonPushed, true);
            app.RBFButton.Text = 'RBF核';
            app.Button_30 = uibutton(app.Tab_3, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @PolynomialKernelButtonPushed, true);
            app.Button_30.Text = '多项式核';
            app.Button_29 = uibutton(app.Tab_3, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @LinearKernelButtonPushed, true);
            app.Button_29.Text = '线性核';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA过程展示';
            app.Image12 = uiimage(app.PCATab);
            app.Image12.ImageSource = fullfile(pathToMLAPP, '【哲风壁纸】大海-帆船-海景.png');
            app.Button_31 = uibutton(app.PCATab, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '1读取训练集图像数据';
            app.Button_32 = uibutton(app.PCATab, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = '2计算平均脸';
            app.Button_33 = uibutton(app.PCATab, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_33Pushed, true);
            app.Button_33.Text = '3计算差值脸';
            app.Button_34 = uibutton(app.PCATab, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = '4构建协方差矩阵 (使用小矩阵)';
            app.Button_35 = uibutton(app.PCATab, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = '5构建特征向量空间';
            app.P85Button = uibutton(app.PCATab, 'push');
            app.P85Button.ButtonPushedFcn = createCallbackFcn(app, @P85ButtonPushed, true);
            app.P85Button.Text = '6选择前P个主成分 (贡献率>85%)';
            app.Button_36 = uibutton(app.PCATab, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Button_36Pushed, true);
            app.Button_36.Text = '7投影训练图像到特征空间';
            app.Button_37 = uibutton(app.PCATab, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_37Pushed, true);
            app.Button_37.Text = '8读取测试集';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Tab';
            app.PCALabel = uilabel(app.UIFigure);
            app.PCALabel.Text = '明月科技——PCA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yanyuban

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end